classdef CoROS_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CoROSUIFigure           matlab.ui.Figure
        Toolbar                 matlab.ui.container.Toolbar
        ToggleOnTop             matlab.ui.container.toolbar.ToggleTool
        UserLogButton           matlab.ui.control.Button
        EventIdEditField        matlab.ui.control.NumericEditField
        EventEditFieldLabel     matlab.ui.control.Label
        AddJSONButton           matlab.ui.control.Button
        JSONFileLabel           matlab.ui.control.Label
        LampJSON                matlab.ui.control.Lamp
        ConnectROS              matlab.ui.control.Button
        LampROS                 matlab.ui.control.Lamp
        ROSMasterPortEditField  matlab.ui.control.NumericEditField
        ROSDeviceIPEditField    matlab.ui.control.EditField
        ROSMasterIPLabel        matlab.ui.control.Label
        ConnectExcelButton      matlab.ui.control.Button
        LampExcel               matlab.ui.control.Lamp
        WorkbookLabel           matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        CalibrationTab          matlab.ui.container.Tab
        CreateCalSheetButton    matlab.ui.control.Button
        CalParamsDropDown       matlab.ui.control.DropDown
        DebugPrintButton        matlab.ui.control.Button
        ReadfromECUButton       matlab.ui.control.Button
        SendtoECUButton         matlab.ui.control.Button
        DAQTab                  matlab.ui.container.Tab
        DAQRecordButton         matlab.ui.control.StateButton
        FilterEditField         matlab.ui.control.EditField
        FilterEditFieldLabel    matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        DAQSwitch               matlab.ui.control.Switch
        ContextMenu             matlab.ui.container.ContextMenu
        SaveDAQListMenu         matlab.ui.container.Menu
        LoadDAQListMenu         matlab.ui.container.Menu
    end

    properties (Constant)
        SL_VARMSG_BUFSIZE = 128
        DATATYPE_TABLE = { % each row: {datatype, sizeofbyte, datatypeindex (consistent with function "byteflow2data")}
            'int8', 1, 0;
            'uint8',1, 1;
            'int16', 2, 4;
            'uint16',2, 2;
            'int32', 4, 5;
            'uint32',4, 3;
            'int64', 8, 6;
            'uint64',8, 9;
            'single',4, 7;
            'double',8, 8;
            'boolean',1,1;
            };
        ROS_RX_BUFSIZE = 50

    end


    properties (Access = private)
        JSONFile = ''% App status
        JSONInfo
        ROSDevice
        ROSState
        ROSPublisher
        ROSSubscriber
        ROSMsg_CalCmd
        ROSRxBuffer_ReadFromECU % Buffer to accomodate rx buffers
        CalibrationInterface % CoROSExcelInterface object, interface with Excel COM worksheet

        Signals % Signal list extracted from JSON file
        DAQActiveSignals % subset of signals that DAQ status is ON
        DAQMessages % Configuration of DAQ messages set to ECU
        DAQUIUpdateTimer = [];
        DAQRecordingCount = 0;
        RecordingStartTime = [];
        UserEventLogging = [0, 0];

        HistoryInfo % Save history info for next time load

        callbackRxMsgErrCnt = 0;
        
        RX_MSG_STRUCT_STR
    end

    methods (Access = private)
        % split a m*n matrix to least number of matrix blocks that each
        % block doesn't exceed the size limit of package (depends on data type)
        function [ijsz, splitinfo] = splitmatrix(app, m, n, datatype)
            % output is a 4 column array, left-top index (zero-based) and size of each block
            dtsize = app.DATATYPE_TABLE{strcmp(datatype, app.DATATYPE_TABLE(:,1)), 2};
            npacklim = app.SL_VARMSG_BUFSIZE/dtsize;
            if npacklim >= (m*n)
                ijsz = [0,0,m,n];
                splitinfo = [1,1];
                return;
            end
            % output is a 4 column array, left-top index (zero-based) and size of each block
            nropt = [1, cumprod(factor(npacklim))];
            ncopt = npacklim./nropt;
            nnopt = [nropt;ncopt]'; %possible combination of split cell matrix
            crratio_opt = ncopt./nropt;
            [~,idx] = min(abs(n/m - crratio_opt));
            sz_monoblk = nnopt(idx,:);% size of monoblocks
            mn_monoblk = floor([m,n]./sz_monoblk); % m*n count of monoblocks
            mn_remain = mod([m,n], sz_monoblk);


            % cnt_res = n_mod(:,1)*n+n_mod(:,2)*m-n_mod(:,1).*n_mod(:,2); %count of residual data
            % [~,idx] = min(cnt_res); % get the combination with minimum number of residual cells
            if mn_monoblk(1)<1
                i_split = 0;
                sz_monoblk(1) = m;
                mn_remain(1) = 0;
            else
                i_split = ((1:mn_monoblk(1))-1)*sz_monoblk(1); %zero based
            end
            if mn_monoblk(2)<1
                j_split = 0;
                sz_monoblk(2) = n;
                mn_remain(2) = 0;
            else
                j_split = ((1:mn_monoblk(2))-1)*sz_monoblk(2); %zero based
            end
            [ii,jj] = meshgrid(i_split, j_split); %first split the monoblock part
            ijsz = [ii(:),jj(:)];
            ijsz(:,3:4) = repmat(sz_monoblk, size(ijsz,1),1); %construct the monoblock part first
            ij_edgeanchor = [ijsz(end,1)+ijsz(end,3), ijsz(end,2)+ijsz(end,4)]; %right/bottom most mono block

            % next deal with the edge part
            if mn_remain(1)>0 % if bottommost have residual edge
                n_bt_cmax = floor(npacklim/mn_remain(1)); %bottom edge, maximum number of columns in one pack
                jb = (0:n_bt_cmax:(n-1))'; %bottom j index, zero based
                ib = ones(size(jb))*ij_edgeanchor(1);
                nrb = ones(size(jb))*(m-ib(1));
                ncb = diff([jb;n]);
                ijsz(end+1:end+numel(jb),:) = [ib, jb, nrb, ncb];
            end
            if mn_remain(2)>0 % if rightmost have residual edge
                n_rt_rmax = floor(npacklim/mn_remain(2)); %right edge, maximum number of row in one pack
                ir = (0:n_rt_rmax:(m-1))'; %right i index, zero based
                jr = ones(size(ir))*ij_edgeanchor(2);
                ncr = ones(size(ir))*(n-jr(1));
                nrr = diff([ir;m]);
                ijsz(end+1:end+numel(ir),:) = [ir, jr, nrr, ncr];
            end
            splitinfo = [size(ijsz,1), (m*n/npacklim)];
        end

        %----------------------------------------------------------------------------
        % Callback that process all respnose messages
        function callbackRxMsg(app, rxmsg)
            persistent timebase;
            try
                % disp(['Received Response Code: 0x', dec2hex(rxmsg.Respcode,4)]);
%                 disp(rxmsg);
%                 disp(app.callbackRxMsgErrCnt);
                if app.callbackRxMsgErrCnt > 50
                    return;
                end
                if verLessThan('ros', '1.3')
                    rxmsg_respcode = rxmsg.respcode;
                    rxmsg_timestamp_s = rxmsg.timestamp.sec;
                    rxmsg_timestamp_ns = rxmsg.timestamp.nsec;
                    rxmsg_respstr = rxmsg.respstr;
                    rxmsg_respoption = rxmsg.respoption;
                    rxmsg_data = rxmsg.data;
                else
                    % For MATLAB R2021a/ROS 1.3 version, the first letter of
                    % rossubscriber received message struct is automatically
                    % converted to uppercase
                    rxmsg_respcode = rxmsg.Respcode;
                    rxmsg_timestamp_s = rxmsg.Timestamp.Sec;
                    rxmsg_timestamp_ns = rxmsg.Timestamp.Nsec;
                    rxmsg_respstr = rxmsg.Respstr;
                    rxmsg_respoption = rxmsg.Respoption;
                    rxmsg_data = rxmsg.Data;
                end
                switch rxmsg_respcode
                    % ---0x10: Read From ECU-----------------------------
                    case hex2dec('8010')
                        app.ROSRxBuffer_ReadFromECU.Value((rxmsg_respoption(1):rxmsg_respoption(2))+1) = bytearray2data(rxmsg_data, app.ROSRxBuffer_ReadFromECU.DataTypeId); %Respoption(1:2) is the starting and ending index, zero based
                        if rxmsg_respoption(4)==0 % the 4th element of resoption stores the remaining messages to be transmitted under this condition
                            app.CalibrationInterface.getCurrentCalParam.setFullValue(app.ROSRxBuffer_ReadFromECU.Value);
                            app.CalibrationInterface.getCurrentCalParam.RefreshColor;
                        end
                        % ---0x50: DAQ-----------------------------
                    case hex2dec('8050')
                        currmsgidx = rxmsg_respoption(1); %%Resoption(1): current message index
                        % set time stamp first
                        app.DAQRecordingCount = app.DAQRecordingCount+1;
                        if app.DAQRecordingCount<=1
                            timebase.Sec = rxmsg_timestamp_s;
                            timebase.Nsec = rxmsg_timestamp_ns;
                        end
                        %                     fprintf('#### Current %u - Total %u\n\n', currmsgidx, numel(app.DAQMessages));
                        %                     disp([rxmsg_timestamp, timebase]);
                        ts_s = double((rxmsg_timestamp_s - timebase.Sec)) + double(rxmsg_timestamp_ns)*1e-9; % Convert to s
                        app.DAQMessages(currmsgidx).TimeStampSignal.Value = ts_s;
                        % set each signal value contained in this message
                        sigobjs = app.DAQMessages(currmsgidx).Signals;
                        for i = 1:numel(sigobjs) % update each signal value
                            msgbyteoffset = sigobjs(i).MsgByteOffset; %note, zero based
                            bytelen = sigobjs(i).ByteLength;
                            sigobjs(i).Value = bytearray2data(rxmsg_data(msgbyteoffset+1:msgbyteoffset+bytelen), sigobjs(i).DataTypeId);
                        end
                    otherwise
                end
            catch
                app.callbackRxMsgErrCnt = app.callbackRxMsgErrCnt + 1;
            end
        end

        function DAQ_UIUpdate(app, ~, ~)
            tbldata = get(app.UITable, 'Data');
            tbldata(:,4) = {app.Signals(cell2mat(tbldata(:,2))).Value}';
            set(app.UITable, 'Data', tbldata);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rtpath = fileparts(mfilename('fullpath'));
            historyjson = fullfile(rtpath, 'history_config.json');
            if exist(historyjson,"file")
                app.HistoryInfo = jsondecode(fileread(historyjson));
                set(app.ROSDeviceIPEditField,'Value', app.HistoryInfo.ROSDeviceAddress);
                set(app.ROSMasterPortEditField,'Value', app.HistoryInfo.ROSMasterPort);
            else
                app.HistoryInfo = [];
            end
        end

        % Button pushed function: ConnectExcelButton
        function ConnectExcelButtonPushed(app, event)
            if isempty(app.CalibrationInterface)
                try
                    excel = actxGetRunningServer('excel.application');
                catch
                    errordlg('Open the calibration Excel file first');
                    return;
                end
                app.CalibrationInterface = CoROSExcelInterface(excel.ActiveWorkbook, app.JSONInfo);
                set(app.WorkbookLabel, 'Text', app.CalibrationInterface.Workbook.Name);
                set(event.Source, 'Text', "Disconnect");
                set(app.LampExcel, 'Color', [0 1 0]); %Green
            else
                app.CalibrationInterface = [];
                set(app.WorkbookLabel, 'Text', 'Workbook Not Defined');
                set(event.Source, 'Text', "Connect");
                set(app.LampExcel, 'Color', [0.5 0.5 0.5]); %Gray
            end
        end

        % Button pushed function: AddJSONButton
        function AddJSONButtonPushed(app, event)
            [jsonfile, pathname] = uigetfile('*.json', 'Pick the JSON file generated with model');
            if isequal(jsonfile,0) || isequal(pathname,0)
                return;
            end
            app.JSONFile = fullfile(pathname, jsonfile);
            app.JSONInfo = jsondecode(fileread(app.JSONFile));
            if ~isempty(app.CalibrationInterface)
                app.CalibrationInterface.JSON = app.JSONInfo;
            end
            set(app.JSONFileLabel, 'Text', [jsonfile, ' (', app.JSONInfo.TimeVersion, ')']);
            set(app.LampJSON, 'Color', [0 1 0]); %Green
            set(app.CalParamsDropDown, 'Items', {app.JSONInfo.ParameterTable.Name}');
            set(app.DAQSwitch, 'Value', 'DAQ Off');
            % update signal table
            app.Signals = [];
            for i=1:numel(app.JSONInfo.SignalTable)
                if startsWith(app.JSONInfo.SignalTable(i).DataType, 'Bus:')
                    % TO BE DONE
                else
                    dtinfo = app.DATATYPE_TABLE(strcmp(app.JSONInfo.SignalTable(i).DataType, app.DATATYPE_TABLE(:,1)),:);
                    if isempty(dtinfo) %not supported for other datatypes
                        continue;
                    else
                        if app.JSONInfo.SignalTable(i).Dimension < 0
                            app.JSONInfo.SignalTable(i).Dimension = 1;
                        end
                        for d = 1:app.JSONInfo.SignalTable(i).Dimension
                            if app.JSONInfo.SignalTable(i).Dimension>1
                                subidxstr = sprintf('[%u]', d-1);
                            else
                                subidxstr = '';
                            end
                            if strcmp(app.JSONInfo.SignalTable(i).DataType, 'boolean')
                                tmpdt = 'uint8';
                            else
                                tmpdt = app.JSONInfo.SignalTable(i).DataType;
                            end
                            app.Signals = [app.Signals; CoROSSignal(...
                                'Index', -1, ... % -1 means not asssigned yet
                                'RawID', app.JSONInfo.SignalTable(i).DataID, ...
                                'Name', [app.JSONInfo.SignalTable(i).Name, subidxstr], ...
                                'DataType', tmpdt, ...
                                'DataTypeId', dtinfo{3}, ...
                                'VarByteOffset', (d-1) * dtinfo{2}, ... % zero based
                                'ByteLength', dtinfo{2}, ...
                                'DAQMsgIndex', -1, ... % -1 means not asssigned yet, one based
                                'MsgByteOffset', -1)]; % -1 means not asssigned yet, zero based
                        end
                    end
                end
            end
            if ~isempty(app.Signals)
                tmp = num2cell(1:numel(app.Signals));
                [app.Signals.Index] = deal(tmp{:});
                [app.Signals.DAQOn] = deal(false);
                [app.Signals.Active] = deal(true); % add new field to store active show on/off in table UI
                [app.Signals.Value] = deal(0); % add new field to store signal value
                if ~isempty(app.HistoryInfo) && ~isempty(app.HistoryInfo.DAQSignalNames)
                    for k=1:numel(app.Signals)
                        if ismember(app.Signals(k).Name, app.HistoryInfo.DAQSignalNames)
                            app.Signals(k).DAQOn = true;
                        else
                            app.Signals(k).DAQOn = false;
                        end
                    end
                end
                set(app.UITable, 'Data', [{app.Signals.DAQOn}', {app.Signals.Index}', {app.Signals.Name}', {app.Signals.Value}']);
                app.DAQActiveSignals = app.Signals.getDAQOn;
            else
                errordlg('The JSON file contains no valid signal information.');
            end
        end

        % Button pushed function: CreateCalSheetButton
        function CreateCalSheetButtonPushed(app, event)
            if isempty(app.JSONInfo) || isempty(app.CalibrationInterface)
                return;
            end
            app.CalibrationInterface.JSONtoCalSheet;
        end

        % Button pushed function: ConnectROS
        function ConnectROSPushed(app, event)
            COLOR.LIGHTBLUE = [0.3 0.75 0.93];
            COLOR.DARKBLUE = [0.0 0.45 0.74];
            COLOR.GRAY = [0.5 0.5 0.5];
            COLOR.DARKGREEN = [0.47 0.67 0.19];
            COLOR.GREEN = [0 1 0];
            COLOR.RED = [1 0 0];
            if strcmp(get(event.Source, 'Text'), 'Connect')
                port = get(app.ROSMasterPortEditField, 'Value');
                ipaddr = get(app.ROSDeviceIPEditField,'Value');
                % First locate ROS Device
                if isempty(ipaddr)
                    errordlg('Please specify the IP address of ROS device first');
                    return;
                else
                    try
                        app.ROSDevice = rosdevice(ipaddr);
                        set(app.LampROS, 'Color', COLOR.DARKBLUE); %Set dark blue to indicate ROS Device found
                    catch
                        errordlg(sprintf(['Cannot connect ROS Device on %s, check your ROS setting.\n' ...
                            'Possible cause:\n' ...
                            '- ROS Master not started or its SSH service not started\n'...
                            '- Wrong IP address or port setting given\n'...
                            'help rosdevice/rosinit or check ROS Simulink setting for further information.'], ipaddr));
                        set(app.LampROS, 'Color', COLOR.GRAY); %Set gray
                        return;
                    end
                end
                % Next run ROS Core
                if app.ROSDevice.isCoreRunning
                    set(app.LampROS, 'Color', COLOR.LIGHTBLUE); %Set light blue to indicate ROS Core Running
                else
                    app.ROSDevice.runCore;
                    set(app.LampROS, 'Color',COLOR.LIGHTBLUE); %Set light blue to indicate ROS Core Running
                    fprintf(2, '## CoROS>> Starting ROS Core on %s\n', app.ROSDevice.DeviceAddress);
                end
                try
                    if isempty(port)
                        fprintf(2, '## CoROS>> Port given empty, trying to use <11311> as default');
                        port = 11311;
                    end
                    rosinit(ipaddr, port);
                    set(app.LampROS, 'Color', COLOR.DARKGREEN); %Set green to indicate ROS Node initalized
                catch e
                    if strcmp(e.identifier, 'ros:mlros:node:GlobalNodeRunningError')
                        set(app.LampROS, 'Color', COLOR.DARKGREEN); %Set green to indicate ROS Node initalized
                    else
                        errordlg(sprintf('Cannot initialize ROS Master on %s:%s, check your ROS setting.', ipaddr, port));
                        set(app.LampROS, 'Color', COLOR.RED); %Set gray
                        return;
                    end
                end
                % prepare subscriber and publisher if necessary
                try
                    if isempty(app.ROSSubscriber)
                        app.ROSSubscriber = rossubscriber('/coros_resp',@(~, msg)app.callbackRxMsg(msg),'DataFormat','struct', 'BufferSize', app.ROS_RX_BUFSIZE);
                    end
                    if isempty(app.ROSPublisher) || isempty(app.ROSMsg_CalCmd)
                        [app.ROSPublisher, app.ROSMsg_CalCmd] = rospublisher('/coros_cal', 'coros_msgs/cmdpacket');
                    end
                    set(app.LampROS, 'Color', COLOR.GREEN);
                catch
                    errordlg(sprintf(['Error occured when creating ROS Subscriber/Publisher, check\n',...
                        'if the node publishing topic %s is running\n',...
                        'if the ROS master has been started on target machine'], '/coros_resp'));
                    set(app.LampROS, 'Color', COLOR.RED);
                    return;
                end
                set(event.Source, 'Text', 'Disconnect');
            else % Disconnect
                set(event.Source, 'Text', 'Connect');
                set(app.LampROS, 'Color', COLOR.LIGHTBLUE);
                if ~isempty(app.ROSSubscriber)
                    app.ROSSubscriber.delete; app.ROSSubscriber = [];
                end
                if ~isempty(app.ROSPublisher)
                    app.ROSPublisher.delete; app.ROSPublisher = [];
                end
            end
        end

        % Button pushed function: SendtoECUButton
        function SendtoECUButtonPushed(app, event)
            app.CalibrationInterface.arbitrateCurrentSelection;
            app.ROSMsg_CalCmd.Cmdcode = hex2dec('20'); %0x20, calibration command
            nparam = numel(app.CalibrationInterface.ActiveCalParams);
            if nparam>3
                hwb = waitbar(0, 'Downloading ...');
            end
            for p=1:nparam
                if nparam>3
                    waitbar(p/nparam, hwb, sprintf('Downloading %u of %u: %s...', p, nparam, app.CalibrationInterface.ActiveCalParams(p).Name));
                end
                if isempty(app.CalibrationInterface.ActiveCalParams(p).SelectedDataValue)
                    continue;
                end
                app.CalibrationInterface.CurrentActiveIndex = p;
                app.ROSMsg_CalCmd.Cmdstr = app.CalibrationInterface.ActiveCalParams(p).Name;
                lrtb = app.CalibrationInterface.ActiveCalParams(p).getSelectedSubIndex; %zero based, [l, r, t, b]'
                % split a large block into pieces that can be packed in one message, returns matrix with each row (local index system) [t, l, msize, nsize]
                tlmn_splitdbs = app.splitmatrix(lrtb(4)-lrtb(3)+1, lrtb(2)-lrtb(1)+1, app.CalibrationInterface.ActiveCalParams(p).DataType);
                n_datapack = size(tlmn_splitdbs, 1);
                for i=1:n_datapack
                    tlmn_this = tlmn_splitdbs(i,:);
                    app.ROSMsg_CalCmd.Cmdoption(1:4) = [lrtb(1)+tlmn_this(2), lrtb(1)+tlmn_this(2)+tlmn_this(4)-1, ...
                        lrtb(3)+tlmn_this(1), lrtb(3)+tlmn_this(1)+tlmn_this(3)-1]; %global index, zero based, [l, r, t, b]'
                    packdata = app.CalibrationInterface.ActiveCalParams(p).SelectedDataValue(tlmn_this(1)+1:tlmn_this(1)+tlmn_this(3), tlmn_this(2)+1:tlmn_this(2)+tlmn_this(4));
                    rawdata = feval(app.CalibrationInterface.ActiveCalParams(p).DataType, packdata);
                    app.ROSMsg_CalCmd.Data = data2bytearray(rawdata);
                    app.ROSPublisher.send(app.ROSMsg_CalCmd);
                    pause(0.05);
                end
                app.CalibrationInterface.ActiveCalParams(p).RefreshColor;
            end
            if exist('hwb','var')
                close(hwb);
            end
        end

        % Button pushed function: DebugPrintButton
        function DebugPrintButtonPushed(app, event)
            calname = get(app.CalParamsDropDown, 'Value');
            paratbl = app.JSONInfo.ParameterTable;
            paraitem = paratbl(strcmp({paratbl.Name}', calname));
            app.ROSMsg_CalCmd.Cmdstr = ''; %not used
            app.ROSMsg_CalCmd.Cmdcode = hex2dec('1000'); %0x1000, debug print
            app.ROSMsg_CalCmd.Cmdoption(1) = paraitem.DataID; %use DataID to index
            app.ROSMsg_CalCmd.Data = [];
            app.ROSPublisher.send(app.ROSMsg_CalCmd);
        end

        % Button pushed function: ReadfromECUButton
        function ReadfromECUButtonPushed(app, event)
            app.CalibrationInterface.arbitrateCurrentSelection;
            app.ROSMsg_CalCmd.Cmdcode = hex2dec('10'); %0x20, calibration command
            nparam = numel(app.CalibrationInterface.ActiveCalParams);
            if nparam>3
                hwb = waitbar(0, 'Uploading ...');
            end
            for p=1:numel(app.CalibrationInterface.ActiveCalParams)
                if nparam>3
                    waitbar(p/nparam, hwb, sprintf('Uploading %u of %u: %s...', p, nparam, app.CalibrationInterface.ActiveCalParams(p).Name));
                end
                app.CalibrationInterface.CurrentActiveIndex = p;
                app.CalibrationInterface.ActiveCalParams(p).makeRoom; % insert rows in excel to make space to accomodate data block
                % prepare publish command message
                app.ROSMsg_CalCmd.Cmdstr = app.CalibrationInterface.ActiveCalParams(p).Name;
                % prepare subscriber
                app.ROSRxBuffer_ReadFromECU = struct;
                app.ROSRxBuffer_ReadFromECU.Value = app.CalibrationInterface.ActiveCalParams(p).OriginalData; %prepare data value to be dumped into
                app.ROSRxBuffer_ReadFromECU.DataTypeId = app.DATATYPE_TABLE{strcmp(app.CalibrationInterface.ActiveCalParams(p).DataType, app.DATATYPE_TABLE(:,1)), 3}; %prepare datatype
                % publish
                app.ROSPublisher.send(app.ROSMsg_CalCmd);
                pause(0.1); % wait a while for message transmission
            end
            app.callbackRxMsgErrCnt = 0;
            if exist('hwb','var')
                close(hwb);
            end
        end

        % Value changed function: FilterEditField
        function FilterEditFieldValueChanged(app, event)
            valuestr = app.FilterEditField.Value;
            valuestrcell = regexp(valuestr, '\s*,*\s*','split');
            signames = {app.Signals.Name};
            subsigidx = contains(signames, valuestrcell);
            if any(subsigidx)
                [app.Signals(subsigidx).Active] = deal(true);
                [app.Signals(~subsigidx).Active] = deal(false);
                app.DAQActiveSignals = app.Signals.getDAQOn;
                set(app.UITable, 'Data', [{app.Signals(subsigidx).DAQOn}', {app.Signals(subsigidx).Index}', {app.Signals(subsigidx).Name}', {app.Signals(subsigidx).Value}']);
            else
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            tbldata = get(app.UITable, 'Data');
            if indices(2)==1
                app.Signals(tbldata{indices(1),2}).DAQOn = newData;
                app.DAQActiveSignals = app.Signals.getDAQOn;
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;

        end

        % Value changed function: DAQSwitch
        function DAQSwitchValueChanged(app, event)
            value = app.DAQSwitch.Value;
            if strcmp(value, 'On') % if DAQ On, first download DAQ List
                app.DAQRecordingCount = 0;
                num_daqsigs = numel(app.DAQActiveSignals);
                app.DAQMessages = app.DAQActiveSignals.stuffMessages(app.SL_VARMSG_BUFSIZE);
                app.ROSMsg_CalCmd.Cmdstr = app.JSONInfo.TimeVersion; % information for ECU to check if version is correct
                app.ROSMsg_CalCmd.Cmdcode = hex2dec('40'); %
                NUMSIGS_PERMSG = floor(app.SL_VARMSG_BUFSIZE/8); %number of signals per message, 8 bytes each to describe an ODT element
                n_sigs_in_msgs = ones(1, floor(num_daqsigs/NUMSIGS_PERMSG))*NUMSIGS_PERMSG;
                n_sigs_lastmsg = rem(num_daqsigs, NUMSIGS_PERMSG);
                if n_sigs_lastmsg>0
                    n_sigs_in_msgs = [n_sigs_in_msgs, n_sigs_lastmsg];
                end
                app.ROSMsg_CalCmd.Cmdoption(1) = num_daqsigs; % number of total ODT items
                app.ROSMsg_CalCmd.Cmdoption(7) = numel(n_sigs_in_msgs); % total number of command messages needed to transfer the DAQList
                i_1stsiginmsg = 1:NUMSIGS_PERMSG:num_daqsigs;
                for icmdmsg=1:numel(n_sigs_in_msgs) % fill in each command message and send
                    app.ROSMsg_CalCmd.Cmdoption(2) = n_sigs_in_msgs(icmdmsg); % number of ODT items in this message
                    app.ROSMsg_CalCmd.Cmdoption(8) = icmdmsg; % sequence number of current command message, one based
                    app.ROSMsg_CalCmd.Data = zeros(1,app.SL_VARMSG_BUFSIZE,'uint8');
                    for iodt = 1:n_sigs_in_msgs(icmdmsg)
                        sigobj = app.DAQActiveSignals(i_1stsiginmsg(icmdmsg) + iodt-1);
                        odtitem = zeros(1,8,'uint8');
                        odtitem(1) = sigobj.DAQMsgIndex;
                        odtitem(2) = sigobj.MsgByteOffset;
                        odtitem(3:4) = data2bytearray(uint16(sigobj.RawID));
                        odtitem(5:6) = data2bytearray(uint16(sigobj.VarByteOffset));
                        odtitem(7) = sigobj.ByteLength;
                        app.ROSMsg_CalCmd.Data((iodt-1)*8+1:iodt*8) = odtitem;
                    end
                    app.ROSPublisher.send(app.ROSMsg_CalCmd);
                    pause(0.05);
                end
            end
            % Next send DAQ start/stop message
            app.ROSMsg_CalCmd.Cmdstr = app.JSONInfo.TimeVersion; %not used
            app.ROSMsg_CalCmd.Cmdoption = zeros(1,8,'uint8'); %use DataID to index
            app.ROSMsg_CalCmd.Data = [];
            if isempty(app.DAQUIUpdateTimer)
                app.DAQUIUpdateTimer = timer("BusyMode", "drop", "ExecutionMode", "fixedRate", "Period", 0.5, "Name", "DAQUIUpdateTimer", "TimerFcn", @(tmrobj, tmrevent) DAQ_UIUpdate(app, tmrobj, tmrevent));
            end
            if strcmp(value, 'On')
                app.ROSMsg_CalCmd.Cmdcode = hex2dec('50'); %0x50 COROS_CMDCODE_STARTDAQ
                app.callbackRxMsgErrCnt = 0;
                app.DAQUIUpdateTimer.start;
                set(app.DAQSwitch, 'FontColor', [0 0 1]); %set blue for measurement, like INCA
            else
                app.ROSMsg_CalCmd.Cmdcode = hex2dec('51'); %0x51 COROS_CMDCODE_STOPDAQ
                app.DAQUIUpdateTimer.stop;
                set(app.DAQSwitch, 'FontColor', [0 0 0]); % set black
                b_record = app.DAQRecordButton.Value;
                if b_record
                    set(app.DAQRecordButton, 'Value', false); % stop recording
                    DAQRecordButtonValueChanged(app, []);
                end
            end
            app.ROSPublisher.send(app.ROSMsg_CalCmd);
        end

        % Close request function: CoROSUIFigure
        function CoROSUIFigureCloseRequest(app, event)
            try
                % Save to History JSON file for next loading
                app.HistoryInfo.DAQSignalNames = {app.Signals.getDAQOn.Name}';
                app.HistoryInfo.ROSDeviceAddress = get(app.ROSDeviceIPEditField, 'Value');
                app.HistoryInfo.ROSMasterPort = get(app.ROSMasterPortEditField,'Value');
                % Save history info
                histinfostr = jsonencode(app.HistoryInfo);
                rtpath = fileparts(mfilename('fullpath'));
                historyjson = fullfile(rtpath, 'history_config.json');
                fid = fopen(historyjson,"w+");
                fwrite(fid,histinfostr,'char');
                fclose(fid);
            catch
            end
            % delete objects
            delete(app.DAQUIUpdateTimer);
            delete(app);
        end

        % Value changed function: DAQRecordButton
        function DAQRecordButtonValueChanged(app, event)
            b_record = app.DAQRecordButton.Value;
            if b_record
                set(app.DAQRecordButton, 'Text', 'Recording', 'FontColor', [1 0 0]); %like INCA, set red for recording indication
                if ~strcmp(get(app.DAQSwitch, 'Value'), 'On') % if DAQ measurement not started, start if first
                    set(app.DAQSwitch, 'Value', 'On');
                    app.DAQSwitchValueChanged([]);
                end
                allsignals = [app.DAQActiveSignals; [app.DAQMessages.TimeStampSignal]'];
                app.DAQRecordingCount = 0;
                app.RecordingStartTime = datetime('now');
                app.UserEventLogging = [];
                set(app.EventIdEditField, 'Enable', true);
                set(app.UserLogButton, 'Enable', true);
                allsignals.startGroupRecording;
            else
                set(app.DAQRecordButton, 'Text', 'Record', 'FontColor', [0 0 0]);
                allsignals = [app.DAQActiveSignals; [app.DAQMessages.TimeStampSignal]'];
                set(app.EventIdEditField, 'Enable', false);
                set(app.UserLogButton, 'Enable', false);
                app.RecordingStartTime = [];
                allsignals.stopGroupRecording(app.UserEventLogging);
            end
        end

        % Menu selected function: SaveDAQListMenu
        function SaveDAQListMenuSelected(app, event)
            [filename, pathname] = uiputfile('*.json', 'Specify file to save signal configuration');
            if isequal(filename,0) || isequal(pathname,0)
            else
                JS.DAQSignalNames = {app.Signals.getDAQOn.Name}';
                jsonfile = fullfile(pathname, filename);
                fid = fopen(jsonfile,"w+");
                fwrite(fid,jsonencode(JS),'char');
                fclose(fid);
            end
        end

        % Menu selected function: LoadDAQListMenu
        function LoadDAQListMenuSelected(app, event)
            [filename, pathname] = uigetfile('*.json', 'Pick a JSON file containing DAQ signal list');
            if isequal(filename,0) || isequal(pathname,0)
            else
                jsonfile = fullfile(pathname, filename);
                JS = jsondecode(fileread(jsonfile));
                if ~isempty(app.Signals)
                    [~, ia] = intersect({app.Signals.Name}', JS.DAQSignalNames);
                    [app.Signals(ia).DAQOn] = deal(true);
                    % sync UI table display
                    tbldata = get(app.UITable, 'Data');
                    tbldata(:,1) = {app.Signals([tbldata{:,2}]).DAQOn}';
                    set(app.UITable, 'Data', tbldata);
                end
            end
        end

        % On callback: ToggleOnTop
        function ToggleOnTopOn(app, event)
            app.CoROSUIFigure.WindowStyle = 'alwaysontop';
        end

        % Off callback: ToggleOnTop
        function ToggleOnTopOff(app, event)
            app.CoROSUIFigure.WindowStyle = 'normal';
        end

        % Button pushed function: UserLogButton
        function UserLogButtonButtonPushed(app, event)
            eventid = get(app.EventIdEditField, 'Value');
            dt = datetime("now") - app.RecordingStartTime;
            % shape a square pulse rise and fall that last for KEEPTIME
            if isempty(app.UserEventLogging)
                app.UserEventLogging = [0,0];
            end
            KEEPTIME = 1; % keep the event signal duration
            eventlog = [seconds(dt), eventid; seconds(dt)+KEEPTIME, 0];
            app.UserEventLogging = [app.UserEventLogging; eventlog];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CoROSUIFigure = uifigure('Visible', 'off');
            app.CoROSUIFigure.Name = 'CoROS';
            app.CoROSUIFigure.Icon = 'corosicon.png';
            app.CoROSUIFigure.CloseRequestFcn = createCallbackFcn(app, @CoROSUIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.CoROSUIFigure);
            app.ToggleOnTop = uitoggletool(app.Toolbar);
            app.ToggleOnTop.Tooltip = {'Set always on top'};
            app.ToggleOnTop.Icon = '878621-200.png';
            app.ToggleOnTop.OffCallback = createCallbackFcn(app, @ToggleOnTopOff, true);
            app.ToggleOnTop.OnCallback = createCallbackFcn(app, @ToggleOnTopOn, true);
            app.TabGroup = uitabgroup(app.CoROSUIFigure);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.SendtoECUButton = uibutton(app.CalibrationTab, 'push');
            app.SendtoECUButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoECUButtonPushed, true);
            app.SendtoECUButton.Text = 'Send to ECU';
            app.ReadfromECUButton = uibutton(app.CalibrationTab, 'push');
            app.ReadfromECUButton.ButtonPushedFcn = createCallbackFcn(app, @ReadfromECUButtonPushed, true);
            app.ReadfromECUButton.Text = 'Read from ECU';
            app.DebugPrintButton = uibutton(app.CalibrationTab, 'push');
            app.DebugPrintButton.ButtonPushedFcn = createCallbackFcn(app, @DebugPrintButtonPushed, true);
            app.DebugPrintButton.Text = '[Debug] Print';
            app.CalParamsDropDown = uidropdown(app.CalibrationTab);
            app.CreateCalSheetButton = uibutton(app.CalibrationTab, 'push');
            app.CreateCalSheetButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCalSheetButtonPushed, true);
            app.CreateCalSheetButton.Tooltip = {'Creat a new worksheet in excel and import JSON variables'};
            app.CreateCalSheetButton.Text = 'Create CalSheet';
            app.DAQTab = uitab(app.TabGroup);
            app.DAQTab.Title = 'DAQ';
            app.DAQSwitch = uiswitch(app.DAQTab, 'slider');
            app.DAQSwitch.Items = {'DAQ Off', 'On'};
            app.DAQSwitch.ValueChangedFcn = createCallbackFcn(app, @DAQSwitchValueChanged, true);
            app.DAQSwitch.Value = 'DAQ Off';
            app.UITable = uitable(app.DAQTab);
            app.UITable.ColumnName = {'DAQ'; 'ID'; 'Name'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true false];
            app.UITable.ColumnEditable = [true false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.FilterEditFieldLabel = uilabel(app.DAQTab);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.DAQTab, 'text');
            app.FilterEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.DAQRecordButton = uibutton(app.DAQTab, 'state');
            app.DAQRecordButton.ValueChangedFcn = createCallbackFcn(app, @DAQRecordButtonValueChanged, true);
            app.DAQRecordButton.Text = 'Record';
            app.WorkbookLabel = uilabel(app.CoROSUIFigure);
            app.WorkbookLabel.Text = 'Workbook';
            app.LampExcel = uilamp(app.CoROSUIFigure);
            app.ConnectExcelButton = uibutton(app.CoROSUIFigure, 'push');
            app.ConnectExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectExcelButtonPushed, true);
            app.ConnectExcelButton.Tooltip = {'Connect/Disconnect  Excel workbook'};
            app.ConnectExcelButton.Text = 'Connect';
            app.ROSMasterIPLabel = uilabel(app.CoROSUIFigure);
            app.ROSMasterIPLabel.Text = 'ROS Master IP';
            app.ROSDeviceIPEditField = uieditfield(app.CoROSUIFigure, 'text');
            app.ROSDeviceIPEditField.Tooltip = {'IP Address of the ROS Master'};
            app.ROSMasterPortEditField = uieditfield(app.CoROSUIFigure, 'numeric');
            app.ROSMasterPortEditField.ValueDisplayFormat = '%u';
            app.ROSMasterPortEditField.Value = 11311;
            app.LampROS = uilamp(app.CoROSUIFigure);
            app.ConnectROS = uibutton(app.CoROSUIFigure, 'push');
            app.ConnectROS.ButtonPushedFcn = createCallbackFcn(app, @ConnectROSPushed, true);
            app.ConnectROS.Text = 'Connect';
            app.LampJSON = uilamp(app.CoROSUIFigure);
            app.JSONFileLabel = uilabel(app.CoROSUIFigure);
            app.JSONFileLabel.Text = 'JSON File';
            app.AddJSONButton = uibutton(app.CoROSUIFigure, 'push');
            app.AddJSONButton.ButtonPushedFcn = createCallbackFcn(app, @AddJSONButtonPushed, true);
            app.AddJSONButton.Tooltip = {'Select JSON config file'};
            app.AddJSONButton.Text = 'Add JSON';
            app.EventEditFieldLabel = uilabel(app.CoROSUIFigure);
            app.EventEditFieldLabel.Tooltip = {'User customized event logged in MDF file as a seperate channel'};
            app.EventEditFieldLabel.Text = 'Event';
            app.EventIdEditField = uieditfield(app.CoROSUIFigure, 'numeric');
            app.EventIdEditField.Limits = [0 65535];
            app.EventIdEditField.RoundFractionalValues = 'on';
            app.EventIdEditField.ValueDisplayFormat = '%05u';
            app.EventIdEditField.Enable = 'off';
            app.EventIdEditField.Tooltip = {'User customized event logged in MDF file as a seperate channel'};
            app.EventIdEditField.Value = 1;
            app.UserLogButton = uibutton(app.CoROSUIFigure, 'push');
            app.UserLogButton.ButtonPushedFcn = createCallbackFcn(app, @UserLogButtonButtonPushed, true);
            app.UserLogButton.Enable = 'off';
            app.UserLogButton.Tooltip = {'User customized event logged in MDF file as a seperate channel'};
            app.UserLogButton.Text = 'Log';
            app.ContextMenu = uicontextmenu(app.CoROSUIFigure);
            app.SaveDAQListMenu = uimenu(app.ContextMenu);
            app.SaveDAQListMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDAQListMenuSelected, true);
            app.SaveDAQListMenu.Text = 'Save DAQ List';
            app.LoadDAQListMenu = uimenu(app.ContextMenu);
            app.LoadDAQListMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDAQListMenuSelected, true);
            app.LoadDAQListMenu.Text = 'Load DAQ List';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.CoROSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoROS_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CoROSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CoROSUIFigure)
        end
    end
end